classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CEditField       matlab.ui.control.NumericEditField
        CEditFieldLabel  matlab.ui.control.Label
        BEditField       matlab.ui.control.NumericEditField
        BEditFieldLabel  matlab.ui.control.Label
        AEditField       matlab.ui.control.NumericEditField
        AEditFieldLabel  matlab.ui.control.Label
        RunSimButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
            %store the values for inputs 
            A = app.AEditField.Value;
            B = app.BEditField.Value;
           

            Model = 'Adder';
            %BlockPathA = 'Adder/A';
            %BlockPathB = 'Adder/B';
            %Load the system to memory 
            load_system(Model);

            %specify inputs for the simulation 
            SimIn = Simulink.SimulationInput(Model);
  
            %simIn = simIn.setBlockParameter(BlockPathA,'A',A);
            %simIn = simIn.setBlockParameter(BlockPathB,'B',B);
            SimIn = SimIn.setVariable('A',A,'Workspace',Model);
            SimIn = SimIn.setVariable('B',B,'Workspace',Model);
            %simIn = setBlockParameter()

            %Simulate model and store the output in out 

            out = sim(SimIn);
            Sum = out.yout{1}.Values.Data;

            app.CEditField.Value = Sum;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end